classdef App_Formula1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes3_2             matlab.ui.control.UIAxes
        UIAxes3_3             matlab.ui.control.UIAxes
        IniciarCarreraButton  matlab.ui.control.Button
        UITable               matlab.ui.control.Table
        Switch                matlab.ui.control.Switch
        general               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarCarreraButton
        function IniciarCarreraButtonPushed(app, event)
            syms a0 a1 a2 a3 x
            %%Obtener funcion
            x1=100;     %Punto inicial
            y1=500;
            
            x2=2800;    %Punto Final
            y2=200;     
            
            eq1= a3*x1^3+a2*x1^2+a1*x1+a0==y1;
            eq2= a3*x2^3+a2*x2^2+a1*x2+a0==y2;
            
            %Editables
            x3=775;     %Punto 1
            y3=-3000;
            
            x4=2125;    %Punto 2
            y4=1000;
            
            eq3= a3*x3^3+a2*x3^2+a1*x3+a0==y3;
            eq4= a3*x4^3+a2*x4^2+a1*x4+a0==y4;
            
            sol=solve([eq1,eq2,eq3,eq4],[a0,a1,a2,a3]);%%Solucion lineal de la ecuacion
            
            v1=double(sol.a0); %Se asignan los valores de cada coeficiente
            v2=double(sol.a1);
            v3=double(sol.a2);
            v4=double(sol.a3);
            
            fprintf("y=%.9fx^3+%.9fx^2+%.9fx+%.9f\n",v4,v3,v2,v1); %Se imprime la funcion
            
            %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
            %%Longitud de curvatura
            
            fx=v4*x^3+v3*x^2+v2*x+v1;  %Funcion general 
            fxd=diff(fx);              %Derivada de la funcion
            fxl=sqrt(1+(fxd^2));       
            disp(simplify(diff(fx)));
            
            lg=int(fxl,x,[x1,x2]);     %Longitud de curva
            fprintf("Longitud de curva: %0.4f\n",lg);   %Se imprime la longitud
            %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
            %Obtener minimos del radio de curvatura
            
            %%Radio de curvatura 1
            
            min = 999999;
            for i=x1:1:x2/2     %Se evalua la longitud de curva en la primera mitad de la funcion
                p = ((fxl)^(2/3)) / (diff(fxd));
                psub = subs(p,x,i);
                if (psub < 0)
                    psub = psub * (-1);
                end
                
                if(psub<=min)
                    min = psub;
                    mini = i;
                end
                
                
                %fprintf("Radio%i: %.6f\n", i, psub);
            end
            
            %%Radio de curvatura 2
            min2 = 999999;
            for i=(x2/2)+1:1:x2 %Se evalua la longitud de curva en la segunda mitad de la funcion
                p = ((fxl)^(2/3)) / (diff(fxd));
                psub = subs(p,x,i);
                if (psub < 0)
                    psub = psub * (-1);
                end
                
                if(psub<=min2)
                    min2 = psub;
                    mini2 = i;
                end
                
                
                %fprintf("Radio%i: %.6f\n", i, psub);
            end
            fprintf("El valor minimo del radio %i de curvatura es de: %.12f\n",mini, min);      %Primer punto minimo de la funcion
            fprintf("El valor minimo del radio %i de curvatura es de: %.12f\n",mini2, min2);    %Segundo punto minimo de la funcion
            %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
            %%Tangente y punto medio de la grada_1
            
            y=fx;   %Funcion general
            puntox= mini;   %Punto minimo 1 de radio de curvatura
            yprim=diff(y);  %Derivada de la funcion
            m = subs(yprim,x,puntox);   %Se obtiene la pendiente en el minimo en el punto
            puntoy=subs(y,x,puntox);    %Se encuentra el punto y
            fprintf("Pendiente 1 radio curvatura minimo: %.9f\n",m);    %Se imprime la pendiente 
            fprintf("Punto 1 radio curvatura minimo: (%.9f,%.9f)\n",puntox,puntoy); %Se imprime el punto 1
            y2f=m*x-m*puntox+puntoy;    %Se establece la funcion de la recta tangente
            y2t=y2f; %Se crea una nueva variable para la funcion tangente
            fprintf("La funcion en el punto 1 es: y=");
            disp(y2t); %Se imprime la funcion
            if(m>0) %Se evalua si la pendiente es positiva se establece una distancia sobre la tangente de -20
                f1 =-20;
            else    %Si es negativa la distancia es de 20 sobre la tangente
                f1 =20;
            end
            x2f= puntox+f1*sqrt(1/(1+m^2)); %Se obtiene la X sobre la tangente que de distancia sea 20
            y2f= puntoy+m*f1*sqrt(1/(1+m^2));   %Se obtiene la Y sobre la tangente que de distancia sea 20
            fprintf("Posicion del centro de la 1er grada: (%.9f,%.9f)\n",x2f,y2f);   %Se imprime el punto central frontal de las gradas
            
            %%Tangente y punto medio de la grada_2
            
            y_2=fx; %Funcion general
            puntox_2= mini2;    %Punto minimo 2 de radio de curvatura
            yprim_2=diff(y);    %Derivada de la funcion
            m_2 = subs(yprim,x,puntox_2);   %Se obtiene la pendiente en el minimo en el punto
            puntoy_2=subs(y,x,puntox_2);    %Se encuentra el punto Y
            fprintf("Pendiente 2 radio de curvatura minimo: %.9f\n",m_2);   %Se imprime la pendiente
            fprintf("Punto 2 radio de curvatura minimo: (%.9f,%.9f)\n",puntox_2,puntoy_2); %Se imprime el punto 2
            y2f_2=m_2*x-m_2*puntox_2+puntoy_2;  %Se establece la funcion de la recta tangente
            y2t_2 = y2f_2;  %Se crea una nueva variable para la funcion tangente
            fprintf("La funcion en el punto 2 es: y=");   
            disp(y2t_2);    %Se imrpime la funcion
            if(m_2>0)   %Se evalua si la pendiente es positiva se establece una distancia sobre la tangente de 20
                f1_2 =20;
            else        %Si es negativa la distancia es de -20 sobre la tangente
                f1_2 =-20;
            end
            x2f_2= puntox_2+f1_2*sqrt(1/(1+m_2^2)); %Se obtiene la X sobre la tangente que de distancia sea 20
            y2f_2= puntoy_2+m_2*f1_2*sqrt(1/(1+m_2^2)); %Se obtiene la Y sobre la tangente que de distancia sea 20
            fprintf("Posicion del centro de la 2da grada: (%.9f,%.9f)\n",x2f_2,y2f_2);   %Se imprime el punto central frontal de las gradas
            %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
            %obtener puntos de la 1er barrera
            xgrada1_i=x2f-40; %Esquina izquierda de la barrera
            ygrada1_i=y2f;  
            
            xgrada1_f=x2f+40; %Esquina derecha de la barrera
            ygrada1_f=y2f;
            
            fprintf("Coordenadas barrera1: (%.9f,%.9f),(%.9f,%.9f)\n",xgrada1_i,ygrada1_i,xgrada1_f,ygrada1_f);
            
            %obtener puntos de la 2da barrera
            xgrada2_i=x2f_2-40; %Esquina izquierda de la barrera
            ygrada2_i=y2f_2;
            
            xgrada2_f=x2f_2+40; %Esquina derecha de la barrera
            ygrada2_f=y2f_2;
            
            fprintf("Coordenadas barrera2: (%.9f,%.9f),(%.9f,%.9f)\n",xgrada2_i,ygrada2_i,xgrada2_f,ygrada2_f); %Se imprimen las coordenadas
            %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
            %Puntos relevantes de la funcion
            
            %Puntos criticos
            fder = diff(fx);    %Se obtiene la derivada de la funcion
            fder = simplify(fder);  %Se simplifica la derivada de la funcion (a decimales)
            crit_p = solve(fder);   %Se aplica solucion lineal a la funcion, obteniendo los puntos minimos/maximos
            fprintf("Puntos Criticos: ");
            disp(crit_p);
            
            %Puntos de inflexion
            fder = diff(diff(fx));  %Se obtiene la segunda derivada
            inflec_pt = solve(fder,'MaxDegree',3);  %Se resuelve la segunda derivada donde cambia la concavidad de la funcion de 3 grado
            idx = imag(double(inflec_pt)) == 0;     %Se resuelve donde es 0 en la funcion
            inflec_pt = inflec_pt(idx);             %Se obtienen y almacenan las coordenadas del punto de inflexion
            fprintf("Puntos Inflexion: ");
            disp(inflec_pt);
            
            %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
            %Plot graph
            
            %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
            %Graficar funcion general
            
            %Graficar gradas
            %figure(100);
            
            
            %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
            %Graficar particula
            x = 100:.01:2800;
            y = -0.000002249*x.^3+0.010770716*x.^2-13.061982421*x+1700.740063337;
            px = 100;
            py = 500;
            for i=1:100
                figure(100); %This is so it will replot over the previous figure, and not make a new one.
                hold off
                %Punto inicial
                app.general.plot(x1,y1,'or');
                hold on
                %Punto final
                app.general.plot(x2,y2,'ob');
                hold on
                %Funcion general
                app.general.fplot(fx,[x1,x2],'color',[0 0 0]);
                hold on
            
                %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
                %Puntos criticos e inflexion
            
                %Puntos criticos
                app.general.plot(double(crit_p), double(subs(fx,crit_p)),'rx'); %Se grafican todos los puntos criticos/max/min de la funcion con una x en color rojo
                app.general.text(double(crit_p), double(subs(fx,crit_p)),'Minimo/Maximo Local');    %Se agrega texto sobre las coordenadas de los puntos
                hold on
            
                %Punto inflexion
                app.general.plot(double(inflec_pt), double(subs(fx,inflec_pt)),'bx');   %Se grafican los puntos de inflexion con una x en color azul
                app.general.text(double(inflec_pt), double(subs(fx,inflec_pt)),'Punto inflexion')   %Se agrega texto sobre las coordenadas de los puntos
                hold on
            
                %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
                %Tangentes
            
                %Tangente punto 1 minimo del radio
                app.general.fplot(y2t,[x1 x2], '--','color',[.32 .133 .9]); %Se grafica la funcion tangente del punto 1 minimo del radio 
                hold on
                %Tangente punto 2 minimo del radio
                app.general.fplot(y2t_2,[x1 x2], '--','color',[.32 .133 .9]);   %Se grafica la funcion tangente del punto 2 minimo del radio
                hold on
            
                %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
                %Puntos con radio menor a 100
            
                %Punto 1 radio<=100
                app.general.plot(puntox, puntoy, 'or'); %Se grafica el punto 1 minimo del radio
                hold on
            
                %Punto 2 radio<=100
                app.general.plot(puntox_2, puntoy_2, 'or'); %Se grafica el punto 1 minimo del radio
                hold on
            
                %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
                %Punto Central Gradas 1
                app.general.plot(x2f, y2f, 'o', 'color',[.79 .5 .181]); %Se grafica el punto central de la primer grada/barrera
                hold on
            
                %Punto Central Gradas 2
                app.general.plot(x2f_2, y2f_2, 'o','color',[.79 .5 .181]);  %Se grafica el punto central de la segunda grada/barrera
                hold on
            
                %%Grada 1
                app.general.plot(xgrada1_i,ygrada1_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la primer grada/barrera
                hold on
                app.general.plot(xgrada1_f,ygrada1_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la segunda grada/barrera
                app.general.plot([xgrada1_i, xgrada1_f], [ygrada1_f, ygrada1_f],'color',[0 0 0]);   %Se grafica una linea horizontal entre el izquierdo 1 y derecho 1
                gx1_i=[xgrada1_i xgrada1_i];   
                gy1_i=[ygrada1_f ygrada1_f-10];
                app.general.plot(gx1_i,gy1_i,'color',[0 0 0]);  %Se grafica una linea vertical entre el punto izquierdo 1 e izquierdo 2
            
                hold on
                app.general.plot(xgrada1_i,ygrada1_f-10,'o','color',[0 0 0]);
                hold on
                app.general.plot(xgrada1_f,ygrada1_f-10,'o','color',[0 0 0]);
                app.general.plot([xgrada1_i, xgrada1_f], [ygrada1_f-10, ygrada1_f-10],'color',[0 0 0]); %Se grafica una linea horizontal entre el izquierdo 2 y derecho 2 
                gx1_f=[xgrada1_f xgrada1_f];
                gy1_f=[ygrada1_f ygrada1_f-10];
                app.general.plot(gx1_f,gy1_f,'color',[0 0 0]);  %Se grafica una linea vertical entre el punto derecho 1 y derecho 2
            
                hold on
            
                %Gradas 2
                app.general.plot(xgrada2_i,ygrada2_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la primer grada/barrera
                hold on
                app.general.plot(xgrada2_f,ygrada2_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la segunda grada/barrera
                app.general.plot([xgrada2_i, xgrada2_f], [ygrada2_f, ygrada2_f],'color',[0 0 0]) %Se grafica una linea horizontal entre el izquierdo 1 y derecho 1 
                gx2_i=[xgrada2_i xgrada2_i];
                gy2_i=[ygrada2_f ygrada2_f+10];
                app.general.plot(gx2_i,gy2_i,'color',[0 0 0]);  %Se grafica una linea vertical entre el punto izquierdo 1 e izquierdo 2
            
                hold on
                app.general.plot(xgrada2_i,ygrada2_f+10,'o','color',[0 0 0]);
                hold on
                app.general.plot(xgrada2_f,ygrada2_f+10,'o','color',[0 0 0]);
                app.general.plot([xgrada2_i, xgrada2_f], [ygrada2_f+10, ygrada2_f+10],'color',[0 0 0]) %Se grafica una linea horizontal entre el derecho 1 e izquierdo 2 
            
                gx2_f=[xgrada2_f xgrada2_f];
                gy2_f=[ygrada2_f ygrada2_f+10];
                app.general.plot(gx2_f,gy2_f,'color',[0 0 0]); %Se grafica una linea vertical entre el punto derecho 1 y derecho 2
                
                ph = app.general.plot(px, py,'o');
                
                %pause(0.1);
                px = px+2800/100;
                py = -0.000002249*px^3+0.010770716*px^2-13.061982421*px+1700.740063337;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.general = uiaxes(app.UIFigure);
            title(app.general, 'Title')
            xlabel(app.general, 'X')
            ylabel(app.general, 'Y')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X'; 'Y'; 'Velocidad'; 'Aceleracion'};
            app.UITable.RowName = {};
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Sin Derrape', 'Derrape'};
            app.Switch.Value = 'Sin Derrape';
            app.UIAxes3_2 = uiaxes(app.UIFigure);
            title(app.UIAxes3_2, 'Title')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            app.UIAxes3_3 = uiaxes(app.UIFigure);
            title(app.UIAxes3_3, 'Title')
            xlabel(app.UIAxes3_3, 'X')
            ylabel(app.UIAxes3_3, 'Y')
            app.IniciarCarreraButton = uibutton(app.UIFigure, 'push');
            app.IniciarCarreraButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarCarreraButtonPushed, true);
            app.IniciarCarreraButton.Text = 'Iniciar Carrera';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Formula1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end