classdef Segundo_entregable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        SimularButton                   matlab.ui.control.Button
        CargaEditField_2Label           matlab.ui.control.Label
        carga2                          matlab.ui.control.NumericEditField
        PosicinenelejeEditField_3Label  matlab.ui.control.Label
        pos2                            matlab.ui.control.NumericEditField
        CargaEditFieldLabel             matlab.ui.control.Label
        carga1                          matlab.ui.control.NumericEditField
        PosicinenelejeEditField_2Label  matlab.ui.control.Label
        pos1                            matlab.ui.control.NumericEditField
        TipodedistribucinSwitchLabel    matlab.ui.control.Label
        distribucion                    matlab.ui.control.Switch
        DistanciaLabel                  matlab.ui.control.Label
        distancia                       matlab.ui.control.NumericEditField
        Distribucin2Label               matlab.ui.control.Label
        Distribucin1Label               matlab.ui.control.Label
        PosicionamientoLabel            matlab.ui.control.Label
        DistanciaLabel_2                matlab.ui.control.Label
        distancia2                      matlab.ui.control.NumericEditField
        DistanciaLabel_3                matlab.ui.control.Label
        distancia1                      matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    methods (Access = private)

        function vertical_array(app)
            %array 1 de cargas
            carga1 = app.carga1.Value;
            pos1 = app.pos1.Value-0.5;
            d1 = app.distancia1.Value;
            
            %array 2 de cargas
            carga2 = app.carga2.Value;
            pos2 = app.pos2.Value-0.5;
            d2 = app.distancia2.Value;
            
            %graficar array 1
            graph_array1(app,pos1,d1,carga1);
            %graficar array2
            graph_array1(app,pos2,d2,carga2);
            
            %añade axis
            axh = app.UIAxes,; % use current axes
            color = 'k'; % black, or [0 0 0]
            linestyle = ':'; % dotted
            line(app.UIAxes,get(axh,'XLim'), [0 0], 'Color', color, 'LineStyle', linestyle);
            line(app.UIAxes,[0 0], get(axh,'YLim'), 'Color', color, 'LineStyle', linestyle);
            
           
        end
        
        function horizontal_array(app)
            %array 1 de cargas
            carga1 = app.carga1.Value;
            pos1 = app.pos1.Value-0.5;
            d1 = app.distancia1.Value;
            
            %array 2 de cargas
            carga2 = app.carga2.Value;
            pos2 = app.pos2.Value-0.5;
            d2 = app.distancia2.Value;
            
            %graficar array 1
            graph_array2(app,pos1,d1,carga1);
            %graficar array2
            graph_array2(app,pos2,d2,carga2);
            
            %añade axis
            axh = app.UIAxes,; % use current axes
            color = 'k'; % black, or [0 0 0]
            linestyle = ':'; % dotted
            line(app.UIAxes,get(axh,'XLim'), [0 0], 'Color', color, 'LineStyle', linestyle);
            line(app.UIAxes,[0 0], get(axh,'YLim'), 'Color', color, 'LineStyle', linestyle);
        end
        
        function graph_array1(app,pos,d,c)
            if(c>0)
                color = 'b';
                chex = [0 0 1];
            elseif c<0
                color = 'r';
                chex = [1 0 0];
            else
                color = 'k';
                chex = [1 1 1];
            end
            
            for i=d:d:(2*d)
                rectangle(app.UIAxes,'Position',[pos i-0.5 1 1.2],'Curvature',[1,1],'FaceColor',color,'EdgeColor',chex);
                
            end
            for i=(-2*d):d:0
                rectangle(app.UIAxes,'Position',[pos i-0.5 1 1.2],'Curvature',[1,1],'FaceColor',color,'EdgeColor',chex);
            end
        end
        
        function graph_array2(app,pos,d,c)
            if(c>0)
                color = 'b';
                chex = [0 0 1];
            elseif c<0
                color = 'r';
                chex = [1 0 0];
            else
                color = 'k';
                chex = [1 1 1];
            end
                
            for i=d:d:(2*d)
                rectangle(app.UIAxes,'Position',[i-0.5 pos 1 1.2],'Curvature',[1,1],'FaceColor',color,'EdgeColor',chex);
            end
            for i=(-2*d):d:0
                rectangle(app.UIAxes,'Position',[i-0.5 pos 1 1.2],'Curvature',[1,1],'FaceColor',color,'EdgeColor',chex);
            end
        end
        
        function campo(app)
            x1 = app.x1.Value;
            y1 = app.y1.Value;
            
            x2 = app.x2.Value;
            y2 = app.y2.Value;
            
            carga1 = app.carga1.Value;
            carga2 = app.carga2.Value;
            
            puntos = 25;
            cargas=[app.carga1.Value,app.carga2.Value];
            radio = 4;
            
            xcargas=[x1+0.5,x2+0.5];
            ycargas=[y1+0.5,y2+0.5];
            
            epsilon = 8.854e-12;%Escribir el valor de epsilon
            %es un valor para hacer la ecuacion del campo electrico
            k = 1/(4 * pi * epsilon);
            
            %Dimensiones de la grafica
            minx= x1-1;
            maxx= x2+2;
            
            
            if y1>y2
                maxy = y1+2;
                miny= y2-1.5;
            elseif y1<y2
                maxy = y2+2;
                miny= y1-1.5;
            else
                maxy = y1+2;
                miny= y1-1.5;
            end
            
            
            %campo electrico
            campoex = zeros(puntos);
            campoey = zeros(puntos);
            
            %Crear 2 vectores
                 %minimo y maximo para numero de divisiones
            x =linspace(minx,maxx,puntos);%va de menos -1.5 a 1.5 dividido en npuntos
            y =linspace(miny,maxy,puntos);%estos son vectores
            
            %crear malla de puntos
            [xpuntosmalla,ypuntosmalla]=meshgrid(x,y);
            
            %crear un for que recorra las cargas para poder obtener el calculo de campo
            %electrico
            
            for i=1:2
                dx = xpuntosmalla-xcargas(i);%obtiene distancias
                dy = ypuntosmalla-ycargas(i);%obtiene distancias
                
                r = sqrt(dx.^2+dy.^2);%distancias totales/ magnitud
                r3 = r.^3;%distnacias entre donde esta la carga y los elementos que rodean la carga
                
                %aqui es por componentes
                campoex=campoex+k.*cargas(i).*dx./r3;
                campoey=campoey+k.*cargas(i).*dy./r3;
                
            end
            
            campoe_total = sqrt(campoex.^2+campoey.^2);
            disp(campoe_total);
            
            %graficar
            
            quiver(app.UIAxes,xpuntosmalla,ypuntosmalla,campoex./campoe_total,campoey./campoe_total);%se aplica normalizacion
            
            
            app.dx.Value = abs((x2+0.5)-(x1+0.5));
            app.dy.Value = abs((y2+0.5)-(y1+0.5))
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            if(app.distribucion.Value == "Vertical")
                vertical_array(app);
            else
                horizontal_array(app);
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {554, 554};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {637, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RightPanel = uipanel(app.GridLayout);
            app.SimularButton = uibutton(app.RightPanel, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.CargaEditField_2Label = uilabel(app.RightPanel);
            app.CargaEditField_2Label.Text = 'Carga';
            app.carga2 = uieditfield(app.RightPanel, 'numeric');
            app.carga2.Value = 2;
            app.PosicinenelejeEditField_3Label = uilabel(app.RightPanel);
            app.PosicinenelejeEditField_3Label.Text = 'Posición en el eje';
            app.pos2 = uieditfield(app.RightPanel, 'numeric');
            app.pos2.Value = 5;
            app.CargaEditFieldLabel = uilabel(app.RightPanel);
            app.CargaEditFieldLabel.Text = 'Carga';
            app.carga1 = uieditfield(app.RightPanel, 'numeric');
            app.carga1.Value = -2;
            app.PosicinenelejeEditField_2Label = uilabel(app.RightPanel);
            app.PosicinenelejeEditField_2Label.Text = 'Posición en el eje';
            app.pos1 = uieditfield(app.RightPanel, 'numeric');
            app.TipodedistribucinSwitchLabel = uilabel(app.RightPanel);
            app.TipodedistribucinSwitchLabel.Text = 'Tipo de distribución';
            app.distribucion = uiswitch(app.RightPanel, 'slider');
            app.distribucion.Items = {'Vertical', 'Horizontal'};
            app.distribucion.Value = 'Vertical';
            app.DistanciaLabel = uilabel(app.RightPanel);
            app.DistanciaLabel.Text = 'Distancia';
            app.distancia = uieditfield(app.RightPanel, 'numeric');
            app.Distribucin2Label = uilabel(app.RightPanel);
            app.Distribucin2Label.Text = 'Distribución 2';
            app.Distribucin1Label = uilabel(app.RightPanel);
            app.Distribucin1Label.Text = 'Distribución 1';
            app.PosicionamientoLabel = uilabel(app.RightPanel);
            app.PosicionamientoLabel.Text = 'Posicionamiento';
            app.DistanciaLabel_2 = uilabel(app.RightPanel);
            app.DistanciaLabel_2.Text = 'Distancia';
            app.distancia2 = uieditfield(app.RightPanel, 'numeric');
            app.distancia2.Value = 1;
            app.DistanciaLabel_3 = uilabel(app.RightPanel);
            app.DistanciaLabel_3.Text = 'Distancia';
            app.distancia1 = uieditfield(app.RightPanel, 'numeric');
            app.distancia1.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Segundo_entregable

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end