classdef primer_entregable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        xEditFieldLabel               matlab.ui.control.Label
        x1                            matlab.ui.control.NumericEditField
        yEditFieldLabel               matlab.ui.control.Label
        y1                            matlab.ui.control.NumericEditField
        xEditField_2Label             matlab.ui.control.Label
        x2                            matlab.ui.control.NumericEditField
        yEditField_2Label             matlab.ui.control.Label
        y2                            matlab.ui.control.NumericEditField
        Carga2Label                   matlab.ui.control.Label
        Carga1Label                   matlab.ui.control.Label
        SimularButton                 matlab.ui.control.StateButton
        PuntoLabel                    matlab.ui.control.Label
        xEditField_3Label             matlab.ui.control.Label
        x3                            matlab.ui.control.NumericEditField
        yEditField_3Label             matlab.ui.control.Label
        y3                            matlab.ui.control.NumericEditField
        ValoresfinalesLabel           matlab.ui.control.Label
        DistanciaxLabel               matlab.ui.control.Label
        dx                            matlab.ui.control.NumericEditField
        CampoelectricoEditFieldLabel  matlab.ui.control.Label
        campoelectrico                matlab.ui.control.NumericEditField
        CargapuntualEditFieldLabel    matlab.ui.control.Label
        carga1                        matlab.ui.control.NumericEditField
        CargapuntualEditField_2Label  matlab.ui.control.Label
        carga2                        matlab.ui.control.NumericEditField
        DistanciayLabel               matlab.ui.control.Label
        dy                            matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SimularButton
        function SimularButtonValueChanged(app, event)
            cla(app.UIAxes,'reset')
            x1 = app.x1.Value;
            y1 = app.y1.Value;
            
            x2 = app.x2.Value;
            y2 = app.y2.Value;
            
            x3 = app.x3.Value;
            y3 = app.y3.Value;
            %carga positiva*
            rectangle(app.UIAxes,'Position',[x1 y1 1 1.2],'Curvature',[1,1],'FaceColor','b','EdgeColor',[0 0 1]);
            %carga negativa
            rectangle(app.UIAxes,'Position',[x2 y2 1 1.2],'Curvature',[1,1],'FaceColor','r','EdgeColor',[1 0 0]);
            
            %punto a evaluar
            rectangle(app.UIAxes,'Position',[x3 y3 0.5 0.6],'Curvature',[1,1],'FaceColor','k','EdgeColor',[1 1 1]);
            
            axis(app.UIAxes,[1 6 2.5 6])
            
            app.dx.Value = abs((x2+0.5)-(x1+0.5));
            app.dy.Value = abs((y2+0.5)-(y1+0.5))
            

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {517, 517};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {601, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Dos cargas con signos opuestos')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            app.RightPanel = uipanel(app.GridLayout);
            app.xEditFieldLabel = uilabel(app.RightPanel);
            app.xEditFieldLabel.Text = 'x';
            app.x1 = uieditfield(app.RightPanel, 'numeric');
            app.x1.Value = 2;
            app.yEditFieldLabel = uilabel(app.RightPanel);
            app.yEditFieldLabel.Text = 'y';
            app.y1 = uieditfield(app.RightPanel, 'numeric');
            app.y1.Editable = 'off';
            app.y1.Value = 4;
            app.xEditField_2Label = uilabel(app.RightPanel);
            app.xEditField_2Label.Text = 'x';
            app.x2 = uieditfield(app.RightPanel, 'numeric');
            app.x2.Value = 4;
            app.yEditField_2Label = uilabel(app.RightPanel);
            app.yEditField_2Label.Text = 'y';
            app.y2 = uieditfield(app.RightPanel, 'numeric');
            app.y2.Editable = 'off';
            app.y2.Value = 4;
            app.Carga2Label = uilabel(app.RightPanel);
            app.Carga2Label.Text = 'Carga 2';
            app.Carga1Label = uilabel(app.RightPanel);
            app.Carga1Label.Text = 'Carga 1';
            app.SimularButton = uibutton(app.RightPanel, 'state');
            app.SimularButton.ValueChangedFcn = createCallbackFcn(app, @SimularButtonValueChanged, true);
            app.SimularButton.Text = 'Simular';
            app.PuntoLabel = uilabel(app.RightPanel);
            app.PuntoLabel.Text = 'Punto';
            app.xEditField_3Label = uilabel(app.RightPanel);
            app.xEditField_3Label.Text = 'x';
            app.x3 = uieditfield(app.RightPanel, 'numeric');
            app.x3.Value = 3.25;
            app.yEditField_3Label = uilabel(app.RightPanel);
            app.yEditField_3Label.Text = 'y';
            app.y3 = uieditfield(app.RightPanel, 'numeric');
            app.y3.Editable = 'off';
            app.y3.Value = 4.25;
            app.ValoresfinalesLabel = uilabel(app.RightPanel);
            app.ValoresfinalesLabel.Text = 'Valores finales';
            app.DistanciaxLabel = uilabel(app.RightPanel);
            app.DistanciaxLabel.Text = 'Distancia  x';
            app.dx = uieditfield(app.RightPanel, 'numeric');
            app.CampoelectricoEditFieldLabel = uilabel(app.RightPanel);
            app.CampoelectricoEditFieldLabel.Text = 'Campo electrico';
            app.campoelectrico = uieditfield(app.RightPanel, 'numeric');
            app.CargapuntualEditFieldLabel = uilabel(app.RightPanel);
            app.CargapuntualEditFieldLabel.Text = 'Carga puntual';
            app.carga1 = uieditfield(app.RightPanel, 'numeric');
            app.CargapuntualEditField_2Label = uilabel(app.RightPanel);
            app.CargapuntualEditField_2Label.Text = 'Carga puntual';
            app.carga2 = uieditfield(app.RightPanel, 'numeric');
            app.DistanciayLabel = uilabel(app.RightPanel);
            app.DistanciayLabel.Text = 'Distancia  y';
            app.dy = uieditfield(app.RightPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = primer_entregable

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end