classdef Segundo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        SimulacionButton             matlab.ui.control.Button
        MaterialDropDownLabel        matlab.ui.control.Label
        MaterialDropDown             matlab.ui.control.DropDown
        TiempoinicialEditFieldLabel  matlab.ui.control.Label
        TiempoinicialEditField       matlab.ui.control.NumericEditField
        AlturaEditFieldLabel         matlab.ui.control.Label
        AlturaEditField              matlab.ui.control.NumericEditField
        RadioEditFieldLabel          matlab.ui.control.Label
        RadioEditField               matlab.ui.control.NumericEditField
        SimuladordefrenadoporunanillodecorrienteLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        MasaEditFieldLabel           matlab.ui.control.Label
        MasaEditField                matlab.ui.control.NumericEditField
        TiempofinalEditFieldLabel    matlab.ui.control.Label
        TiempofinalEditField         matlab.ui.control.NumericEditField
        VelocidadEditFieldLabel      matlab.ui.control.Label
        VelocidadEditField           matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
         
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulacionButton
        function SimulacionButtonPushed(app, event)
                global m RP R mu a
                t0 = 0;
                tf = 10;
                t = [t0,tf]; %tiempo
                zdata = [10,0]; % Cae de 10m con una velocidad de 0m/s (inicial)
                
                m = 10;             % MASA
                RP =5000  ;         % PERMEABILIDAD RELATIVA MAXIMA HIERRO
                R = 9.71 *10^-8;    % RESISTIVIDAD HIERRO 20°C
                mu = 4*pi*(10^-7);  % PERMEABILIDAD MAG. K
                a = 0.08;           % RADIO
                
                [t,z] = ode45(@fall,t, zdata);
                plot(app.UIAxes,z(:,1));
                
                
                function sistema = fall(t,z)
                    %     Z siendo un vector con los intervalos de Z inicial y diferencial de Z en ese punto,
                    %     requerido asi por ODE45
                    g = 9.81;           % GRAV
                    
                    % IMPLEMENTACIÓN DE ECUACION INICIAL
                    k = (9*(mu * RP)^2 * (a^4))/(4*R);
                    elem_1 = z(2);
                    elem_2 = -((k.*(z(1).^2).*z(2))) ./(m.*((z(1).^2 + a^2).^5)) - g;
                   
                    sistema = [elem_1; elem_2];
               end
               
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {466, 466};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {537, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RightPanel = uipanel(app.GridLayout);
            app.SimulacionButton = uibutton(app.RightPanel, 'push');
            app.SimulacionButton.ButtonPushedFcn = createCallbackFcn(app, @SimulacionButtonPushed, true);
            app.SimulacionButton.Text = 'Simulacion';
            app.MaterialDropDownLabel = uilabel(app.RightPanel);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.RightPanel);
            app.MaterialDropDown.Items = {'Hierro', 'Aluminio', 'Cobre', 'Plomo'};
            app.MaterialDropDown.Value = 'Hierro';
            app.TiempoinicialEditFieldLabel = uilabel(app.RightPanel);
            app.TiempoinicialEditFieldLabel.Text = 'Tiempo inicial';
            app.TiempoinicialEditField = uieditfield(app.RightPanel, 'numeric');
            app.AlturaEditFieldLabel = uilabel(app.RightPanel);
            app.AlturaEditFieldLabel.Text = 'Altura';
            app.AlturaEditField = uieditfield(app.RightPanel, 'numeric');
            app.AlturaEditField.Value = 10;
            app.RadioEditFieldLabel = uilabel(app.RightPanel);
            app.RadioEditFieldLabel.Text = 'Radio';
            app.RadioEditField = uieditfield(app.RightPanel, 'numeric');
            app.RadioEditField.Value = 0.08;
            app.SimuladordefrenadoporunanillodecorrienteLabel = uilabel(app.RightPanel);
            app.SimuladordefrenadoporunanillodecorrienteLabel.Text = 'Simulador de frenado por un anillo de corriente';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '_________________________________________';
            app.MasaEditFieldLabel = uilabel(app.RightPanel);
            app.MasaEditFieldLabel.Text = 'Masa';
            app.MasaEditField = uieditfield(app.RightPanel, 'numeric');
            app.MasaEditField.Value = 10;
            app.TiempofinalEditFieldLabel = uilabel(app.RightPanel);
            app.TiempofinalEditFieldLabel.Text = 'Tiempo final';
            app.TiempofinalEditField = uieditfield(app.RightPanel, 'numeric');
            app.TiempofinalEditField.Value = 20;
            app.VelocidadEditFieldLabel = uilabel(app.RightPanel);
            app.VelocidadEditFieldLabel.Text = 'Velocidad';
            app.VelocidadEditField = uieditfield(app.RightPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Segundo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end